classdef app_clanak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        StartCheckBox          matlab.ui.control.CheckBox
        CiljCheckBox           matlab.ui.control.CheckBox
        PreprekaCheckBox       matlab.ui.control.CheckBox
        b_1_1                  matlab.ui.control.Button
        b_1_2                  matlab.ui.control.Button
        b_1_3                  matlab.ui.control.Button
        b_1_4                  matlab.ui.control.Button
        b_1_5                  matlab.ui.control.Button
        b_1_6                  matlab.ui.control.Button
        b_1_7                  matlab.ui.control.Button
        b_1_8                  matlab.ui.control.Button
        b_1_9                  matlab.ui.control.Button
        b_1_10                 matlab.ui.control.Button
        b_1_11                 matlab.ui.control.Button
        b_1_12                 matlab.ui.control.Button
        b_1_13                 matlab.ui.control.Button
        b_1_14                 matlab.ui.control.Button
        b_1_15                 matlab.ui.control.Button
        b_2_1                  matlab.ui.control.Button
        b_2_2                  matlab.ui.control.Button
        b_2_3                  matlab.ui.control.Button
        b_2_4                  matlab.ui.control.Button
        b_2_5                  matlab.ui.control.Button
        b_2_6                  matlab.ui.control.Button
        b_2_7                  matlab.ui.control.Button
        b_2_8                  matlab.ui.control.Button
        b_2_9                  matlab.ui.control.Button
        b_2_10                 matlab.ui.control.Button
        b_2_11                 matlab.ui.control.Button
        b_2_12                 matlab.ui.control.Button
        b_2_13                 matlab.ui.control.Button
        b_2_14                 matlab.ui.control.Button
        b_2_15                 matlab.ui.control.Button
        b_3_1                  matlab.ui.control.Button
        b_3_2                  matlab.ui.control.Button
        b_3_3                  matlab.ui.control.Button
        b_3_4                  matlab.ui.control.Button
        b_3_5                  matlab.ui.control.Button
        b_3_6                  matlab.ui.control.Button
        b_3_7                  matlab.ui.control.Button
        b_3_8                  matlab.ui.control.Button
        b_3_9                  matlab.ui.control.Button
        b_3_10                 matlab.ui.control.Button
        b_3_11                 matlab.ui.control.Button
        b_3_12                 matlab.ui.control.Button
        b_3_13                 matlab.ui.control.Button
        b_3_14                 matlab.ui.control.Button
        b_3_15                 matlab.ui.control.Button
        b_4_1                  matlab.ui.control.Button
        b_4_2                  matlab.ui.control.Button
        b_4_3                  matlab.ui.control.Button
        b_4_4                  matlab.ui.control.Button
        b_4_5                  matlab.ui.control.Button
        b_4_6                  matlab.ui.control.Button
        b_4_7                  matlab.ui.control.Button
        b_4_8                  matlab.ui.control.Button
        b_4_9                  matlab.ui.control.Button
        b_4_10                 matlab.ui.control.Button
        b_4_11                 matlab.ui.control.Button
        b_4_12                 matlab.ui.control.Button
        b_4_13                 matlab.ui.control.Button
        b_4_14                 matlab.ui.control.Button
        b_4_15                 matlab.ui.control.Button
        b_5_1                  matlab.ui.control.Button
        b_5_2                  matlab.ui.control.Button
        b_5_3                  matlab.ui.control.Button
        b_5_4                  matlab.ui.control.Button
        b_5_5                  matlab.ui.control.Button
        b_5_6                  matlab.ui.control.Button
        b_5_7                  matlab.ui.control.Button
        b_5_8                  matlab.ui.control.Button
        b_5_9                  matlab.ui.control.Button
        b_5_10                 matlab.ui.control.Button
        b_5_11                 matlab.ui.control.Button
        b_5_12                 matlab.ui.control.Button
        b_5_13                 matlab.ui.control.Button
        b_5_14                 matlab.ui.control.Button
        b_5_15                 matlab.ui.control.Button
        b_6_1                  matlab.ui.control.Button
        b_6_2                  matlab.ui.control.Button
        b_6_3                  matlab.ui.control.Button
        b_6_4                  matlab.ui.control.Button
        b_6_5                  matlab.ui.control.Button
        b_6_6                  matlab.ui.control.Button
        b_6_7                  matlab.ui.control.Button
        b_6_8                  matlab.ui.control.Button
        b_6_9                  matlab.ui.control.Button
        b_6_10                 matlab.ui.control.Button
        b_6_11                 matlab.ui.control.Button
        b_6_12                 matlab.ui.control.Button
        b_6_13                 matlab.ui.control.Button
        b_6_14                 matlab.ui.control.Button
        b_6_15                 matlab.ui.control.Button
        b_7_1                  matlab.ui.control.Button
        b_7_2                  matlab.ui.control.Button
        b_7_3                  matlab.ui.control.Button
        b_7_4                  matlab.ui.control.Button
        b_7_5                  matlab.ui.control.Button
        b_7_6                  matlab.ui.control.Button
        b_7_7                  matlab.ui.control.Button
        b_7_8                  matlab.ui.control.Button
        b_7_9                  matlab.ui.control.Button
        b_7_10                 matlab.ui.control.Button
        b_7_11                 matlab.ui.control.Button
        b_7_12                 matlab.ui.control.Button
        b_7_13                 matlab.ui.control.Button
        b_7_14                 matlab.ui.control.Button
        b_7_15                 matlab.ui.control.Button
        b_8_1                  matlab.ui.control.Button
        b_8_2                  matlab.ui.control.Button
        b_8_3                  matlab.ui.control.Button
        b_8_4                  matlab.ui.control.Button
        b_8_5                  matlab.ui.control.Button
        b_8_6                  matlab.ui.control.Button
        b_8_7                  matlab.ui.control.Button
        b_8_8                  matlab.ui.control.Button
        b_8_9                  matlab.ui.control.Button
        b_8_10                 matlab.ui.control.Button
        b_8_11                 matlab.ui.control.Button
        b_8_12                 matlab.ui.control.Button
        b_8_13                 matlab.ui.control.Button
        b_8_14                 matlab.ui.control.Button
        b_8_15                 matlab.ui.control.Button
        b_9_1                  matlab.ui.control.Button
        b_9_2                  matlab.ui.control.Button
        b_9_3                  matlab.ui.control.Button
        b_9_4                  matlab.ui.control.Button
        b_9_5                  matlab.ui.control.Button
        b_9_6                  matlab.ui.control.Button
        b_9_7                  matlab.ui.control.Button
        b_9_8                  matlab.ui.control.Button
        b_9_9                  matlab.ui.control.Button
        b_9_10                 matlab.ui.control.Button
        b_9_11                 matlab.ui.control.Button
        b_9_12                 matlab.ui.control.Button
        b_9_13                 matlab.ui.control.Button
        b_9_14                 matlab.ui.control.Button
        b_9_15                 matlab.ui.control.Button
        b_10_1                 matlab.ui.control.Button
        b_10_2                 matlab.ui.control.Button
        b_10_3                 matlab.ui.control.Button
        b_10_4                 matlab.ui.control.Button
        b_10_5                 matlab.ui.control.Button
        b_10_6                 matlab.ui.control.Button
        b_10_7                 matlab.ui.control.Button
        b_10_8                 matlab.ui.control.Button
        b_10_9                 matlab.ui.control.Button
        b_10_10                matlab.ui.control.Button
        b_10_11                matlab.ui.control.Button
        b_10_12                matlab.ui.control.Button
        b_10_13                matlab.ui.control.Button
        b_10_14                matlab.ui.control.Button
        b_10_15                matlab.ui.control.Button
        b_11_1                 matlab.ui.control.Button
        b_11_2                 matlab.ui.control.Button
        b_11_3                 matlab.ui.control.Button
        b_11_4                 matlab.ui.control.Button
        b_11_5                 matlab.ui.control.Button
        b_11_6                 matlab.ui.control.Button
        b_11_7                 matlab.ui.control.Button
        b_11_8                 matlab.ui.control.Button
        b_11_9                 matlab.ui.control.Button
        b_11_10                matlab.ui.control.Button
        b_11_11                matlab.ui.control.Button
        b_11_12                matlab.ui.control.Button
        b_11_13                matlab.ui.control.Button
        b_11_14                matlab.ui.control.Button
        b_11_15                matlab.ui.control.Button
        b_12_1                 matlab.ui.control.Button
        b_12_2                 matlab.ui.control.Button
        b_12_3                 matlab.ui.control.Button
        b_12_4                 matlab.ui.control.Button
        b_12_5                 matlab.ui.control.Button
        b_12_6                 matlab.ui.control.Button
        b_12_7                 matlab.ui.control.Button
        b_12_8                 matlab.ui.control.Button
        b_12_9                 matlab.ui.control.Button
        b_12_10                matlab.ui.control.Button
        b_12_11                matlab.ui.control.Button
        b_12_12                matlab.ui.control.Button
        b_12_13                matlab.ui.control.Button
        b_12_14                matlab.ui.control.Button
        b_12_15                matlab.ui.control.Button
        b_13_1                 matlab.ui.control.Button
        b_13_2                 matlab.ui.control.Button
        b_13_3                 matlab.ui.control.Button
        b_13_4                 matlab.ui.control.Button
        b_13_5                 matlab.ui.control.Button
        b_13_6                 matlab.ui.control.Button
        b_13_7                 matlab.ui.control.Button
        b_13_8                 matlab.ui.control.Button
        b_13_9                 matlab.ui.control.Button
        b_13_10                matlab.ui.control.Button
        b_13_11                matlab.ui.control.Button
        b_13_12                matlab.ui.control.Button
        b_13_13                matlab.ui.control.Button
        b_13_14                matlab.ui.control.Button
        b_13_15                matlab.ui.control.Button
        b_14_1                 matlab.ui.control.Button
        b_14_2                 matlab.ui.control.Button
        b_14_3                 matlab.ui.control.Button
        b_14_4                 matlab.ui.control.Button
        b_14_5                 matlab.ui.control.Button
        b_14_6                 matlab.ui.control.Button
        b_14_7                 matlab.ui.control.Button
        b_14_8                 matlab.ui.control.Button
        b_14_9                 matlab.ui.control.Button
        b_14_10                matlab.ui.control.Button
        b_14_11                matlab.ui.control.Button
        b_14_12                matlab.ui.control.Button
        b_14_13                matlab.ui.control.Button
        b_14_14                matlab.ui.control.Button
        b_14_15                matlab.ui.control.Button
        b_15_1                 matlab.ui.control.Button
        b_15_2                 matlab.ui.control.Button
        b_15_3                 matlab.ui.control.Button
        b_15_4                 matlab.ui.control.Button
        b_15_5                 matlab.ui.control.Button
        b_15_6                 matlab.ui.control.Button
        b_15_7                 matlab.ui.control.Button
        b_15_8                 matlab.ui.control.Button
        b_15_9                 matlab.ui.control.Button
        b_15_10                matlab.ui.control.Button
        b_15_11                matlab.ui.control.Button
        b_15_12                matlab.ui.control.Button
        b_15_13                matlab.ui.control.Button
        b_15_14                matlab.ui.control.Button
        b_15_15                matlab.ui.control.Button
        ClearStartButton       matlab.ui.control.Button
        ClearCiljButton        matlab.ui.control.Button
        ClearPreprekaButton    matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        epsilonSliderLabel     matlab.ui.control.Label
        epsilonSlider          matlab.ui.control.Slider
        populationSliderLabel  matlab.ui.control.Label
        populationSlider       matlab.ui.control.Slider
        generationSliderLabel  matlab.ui.control.Label
        generationSlider       matlab.ui.control.Slider
        lambdaSliderLabel      matlab.ui.control.Label
        lambdaSlider           matlab.ui.control.Slider
        crossoverSliderLabel   matlab.ui.control.Label
        crossoverSlider        matlab.ui.control.Slider
        mutationSliderLabel    matlab.ui.control.Label
        mutationSlider         matlab.ui.control.Slider
        ClearPathButton        matlab.ui.control.Button
        Tab2                   matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        start_checkbox = [];
        cilj_checkbox = [];
        prepreke_checkboxes = [];
        calculate = 1;
        my_best_path = [];
    end
    
    methods (Access = private)
        
        function results =mark_button(app, my_tag, my_button)
            results = 1;
            my_button.Tag = my_tag;

            if((app.StartCheckBox.Value == 1) && strcmp(my_button.Enable,'on'))
                my_button.BackgroundColor = 'green';
                my_button.Enable = false;
                app.StartCheckBox.Enable = false;
                app.StartCheckBox.Value = 0;
                app.PreprekaCheckBox.Enable = true;
                if(length(app.cilj_checkbox) ==0)
                    app.CiljCheckBox.Enable = true;
                end
                app.start_checkbox = [app.start_checkbox,  my_button];
            end
            if(app.CiljCheckBox.Value == 1 && strcmp(my_button.Enable,'on'))
                app.cilj_checkbox = [app.cilj_checkbox, my_button];
                my_button.BackgroundColor = 'cyan';
                 my_button.Enable = false;
                app.CiljCheckBox.Enable = false;
                app.CiljCheckBox.Value = 0;
                app.PreprekaCheckBox.Enable = true;
                if(length(app.start_checkbox) ==0)
                    app.StartCheckBox.Enable = true;
                end
            end
            if(app.PreprekaCheckBox.Value == 1 && strcmp(my_button.Enable,'on'))
                my_button.BackgroundColor = 'magenta';
                my_button.Enable = false;
                 if(length(app.cilj_checkbox) ==0)
                    app.CiljCheckBox.Enable = true;
                 end
                 if(length(app.start_checkbox) ==0)
                    app.StartCheckBox.Enable = true;
                 end
                 app.prepreke_checkboxes = [app.prepreke_checkboxes,my_button];
            end
        end
        
        function results = clear_button(app, my_array, my_checkbox)
            for i = 1:length(my_array)
                my_array(i).BackgroundColor = [0.94,0.94,0.94];
                my_array(i).Enable = true;
            end
            my_checkbox.Enable = true;
            my_checkbox.Value = 0;
            results=1;
        end
    end
    

    methods (Access = private)

        % Value changed function: StartCheckBox
        function StartCheckBoxValueChanged(app, event)
            value = app.StartCheckBox.Value;
            if(value == 1)
                
                app.CiljCheckBox.Enable = false;
                app.PreprekaCheckBox.Enable = false;
            else
                if(length(app.cilj_checkbox) ==0)
                   app.CiljCheckBox.Enable = true;
                end
                app.PreprekaCheckBox.Enable = true;
            end
        end

        % Value changed function: CiljCheckBox
        function CiljCheckBoxValueChanged(app, event)
            value = app.CiljCheckBox.Value;
            if(value == 1)
                app.StartCheckBox.Enable = false;
                app.PreprekaCheckBox.Enable = false;
            else
                if(length(app.start_checkbox) ==0)
                   app.StartCheckBox.Enable = true;
                end
                app.PreprekaCheckBox.Enable = true;
            end
        end

        % Value changed function: PreprekaCheckBox
        function PreprekaCheckBoxValueChanged(app, event)
            value = app.PreprekaCheckBox.Value;
            if(value == 1)
                app.StartCheckBox.Enable = false;
                app.CiljCheckBox.Enable = false;
            else
                
                if(length(app.start_checkbox) ==0)
                   app.StartCheckBox.Enable = true;
                end
                if(length(app.cilj_checkbox) ==0)
                    app.CiljCheckBox.Enable = true;
                end
            end
        end

        % Button pushed function: b_1_1
        function b_1_1ButtonPushed(app, event)
            
            app.mark_button('1 1', app.b_1_1);
            
        end

        % Button pushed function: b_1_2
        function b_1_2ButtonPushed(app, event)
             app.mark_button('1 2',app.b_1_2);
        end

        % Button pushed function: b_1_3
        function b_1_3ButtonPushed(app, event)
               
            app.mark_button('1 3',app.b_1_3);
        end

        % Button pushed function: b_1_4
        function b_1_4ButtonPushed(app, event)
               app.mark_button('1 4',app.b_1_4);
        end

        % Button pushed function: b_1_5
        function b_1_5ButtonPushed(app, event)
               app.mark_button('1 5',app.b_1_5);
        end

        % Button pushed function: b_1_6
        function b_1_6ButtonPushed(app, event)
                app.mark_button('1 6',app.b_1_6);
        end

        % Button pushed function: b_1_7
        function b_1_7ButtonPushed(app, event)
               app.mark_button('1 7',app.b_1_7);
        end

        % Button pushed function: b_1_8
        function b_1_8ButtonPushed(app, event)
            app.mark_button('1 8',app.b_1_8);
        end

        % Button pushed function: b_1_9
        function b_1_9ButtonPushed(app, event)
               app.mark_button('1 9',app.b_1_9);
        end

        % Button pushed function: b_1_10
        function b_1_10ButtonPushed(app, event)
              app.mark_button('1 10',app.b_1_10);
        end

        % Button pushed function: b_1_11
        function b_1_11ButtonPushed(app, event)
               app.mark_button('1 11',app.b_1_11);
        end

        % Button pushed function: b_1_12
        function b_1_12ButtonPushed(app, event)
                app.mark_button('1 12',app.b_1_12);
        end

        % Button pushed function: b_1_13
        function b_1_13ButtonPushed(app, event)
               app.mark_button('1 13',app.b_1_13);
        end

        % Button pushed function: b_1_14
        function b_1_14ButtonPushed(app, event)
               app.mark_button('1 14',app.b_1_14);
        end

        % Button pushed function: b_1_15
        function b_1_15ButtonPushed(app, event)
              app.mark_button('1 15',app.b_1_15);
        end

        % Button pushed function: b_2_1
        function b_2_1ButtonPushed(app, event)
            app.mark_button('2 1',app.b_2_1);
        end

        % Button pushed function: b_2_2
        function b_2_2ButtonPushed(app, event)
               app.mark_button('2 2',app.b_2_2);
        end

        % Button pushed function: b_2_3
        function b_2_3ButtonPushed(app, event)
              app.mark_button('2 3',app.b_2_3);
        end

        % Button pushed function: b_2_4
        function b_2_4ButtonPushed(app, event)
              app.mark_button('2 4',app.b_2_4);
        end

        % Button pushed function: b_2_5
        function b_2_5ButtonPushed(app, event)
           app.mark_button('2 5',app.b_2_5);
        end

        % Button pushed function: b_2_6
        function b_2_6ButtonPushed(app, event)
           app.mark_button('2 6',app.b_2_6);
        end

        % Button pushed function: b_2_7
        function b_2_7ButtonPushed(app, event)
           app.mark_button('2 7',app.b_2_7);
        end

        % Button pushed function: b_2_8
        function b_2_8ButtonPushed(app, event)
           app.mark_button('2 8',app.b_2_8);
        end

        % Button pushed function: b_2_9
        function b_2_9ButtonPushed(app, event)
           app.mark_button('2 9',app.b_2_9);
        end

        % Button pushed function: b_2_10
        function b_2_10ButtonPushed(app, event)
           app.mark_button('2 10',app.b_2_10);
        end

        % Button pushed function: b_2_11
        function b_2_11ButtonPushed(app, event)
           app.mark_button('2 11',app.b_2_11);
        end

        % Button pushed function: b_2_12
        function b_2_12ButtonPushed(app, event)
           app.mark_button('2 12',app.b_2_12);
        end

        % Button pushed function: b_2_13
        function b_2_13ButtonPushed(app, event)
           app.mark_button('2 13',app.b_2_13);
        end

        % Button pushed function: b_2_14
        function b_2_14ButtonPushed(app, event)
           app.mark_button('2 14',app.b_2_14);
        end

        % Button pushed function: b_2_15
        function b_2_15ButtonPushed(app, event)
           app.mark_button('2 15',app.b_2_15);
        end

        % Button pushed function: b_3_1
        function b_3_1ButtonPushed(app, event)
           app.mark_button('3 1',app.b_3_1);
        end

        % Button pushed function: b_3_2
        function b_3_2ButtonPushed(app, event)
           app.mark_button('3 2',app.b_3_2);
        end

        % Button pushed function: b_3_3
        function b_3_3ButtonPushed(app, event)
           app.mark_button('3 3',app.b_3_3);
        end

        % Button pushed function: b_3_4
        function b_3_4ButtonPushed(app, event)
           app.mark_button('3 4',app.b_3_4);
        end

        % Button pushed function: b_3_5
        function b_3_5ButtonPushed(app, event)
           app.mark_button('3 5',app.b_3_5);
        end

        % Button pushed function: b_3_6
        function b_3_6ButtonPushed(app, event)
           app.mark_button('3 6',app.b_3_6);
        end

        % Button pushed function: b_3_7
        function b_3_7ButtonPushed(app, event)
           app.mark_button('3 7',app.b_3_7);
        end

        % Button pushed function: b_3_8
        function b_3_8ButtonPushed(app, event)
           app.mark_button('3 8',app.b_3_8);
        end

        % Button pushed function: b_3_9
        function b_3_9ButtonPushed(app, event)
           app.mark_button('3 9',app.b_3_9);
        end

        % Button pushed function: b_3_10
        function b_3_10ButtonPushed(app, event)
           app.mark_button('3 10',app.b_3_10);
        end

        % Button pushed function: b_3_11
        function b_3_11ButtonPushed(app, event)
           app.mark_button('3 11',app.b_3_11);
        end

        % Button pushed function: b_3_12
        function b_3_12ButtonPushed(app, event)
           app.mark_button('3 12',app.b_3_12);
        end

        % Button pushed function: b_3_13
        function b_3_13ButtonPushed(app, event)
           app.mark_button('3 13',app.b_3_13);
        end

        % Button pushed function: b_3_14
        function b_3_14ButtonPushed(app, event)
           app.mark_button('3 14',app.b_3_14);
        end

        % Button pushed function: b_3_15
        function b_3_15ButtonPushed(app, event)
           app.mark_button('3 15',app.b_3_15);
        end

        % Button pushed function: b_4_1
        function b_4_1ButtonPushed(app, event)
           app.mark_button('4 1',app.b_4_1);
        end

        % Button pushed function: b_4_2
        function b_4_2ButtonPushed(app, event)
           app.mark_button('4 2',app.b_4_2);
        end

        % Button pushed function: b_4_3
        function b_4_3ButtonPushed(app, event)
           app.mark_button('4 3',app.b_4_3);
        end

        % Button pushed function: b_4_4
        function b_4_4ButtonPushed(app, event)
           app.mark_button('4 4',app.b_4_4);
        end

        % Button pushed function: b_4_5
        function b_4_5ButtonPushed(app, event)
           app.mark_button('4 5',app.b_4_5);
        end

        % Button pushed function: b_4_6
        function b_4_6ButtonPushed(app, event)
           app.mark_button('4 6',app.b_4_6);
        end

        % Button pushed function: b_4_7
        function b_4_7ButtonPushed(app, event)
           app.mark_button('4 7',app.b_4_7);
        end

        % Button pushed function: b_4_8
        function b_4_8ButtonPushed(app, event)
           app.mark_button('4 8',app.b_4_8);
        end

        % Button pushed function: b_4_9
        function b_4_9ButtonPushed(app, event)
           app.mark_button('4 9',app.b_4_9);
        end

        % Button pushed function: b_4_10
        function b_4_10ButtonPushed(app, event)
           app.mark_button('4 10',app.b_4_10);
        end

        % Button pushed function: b_4_11
        function b_4_11ButtonPushed(app, event)
           app.mark_button('4 11',app.b_4_11);
        end

        % Button pushed function: b_4_12
        function b_4_12ButtonPushed(app, event)
           app.mark_button('4 12',app.b_4_12);
        end

        % Button pushed function: b_4_13
        function b_4_13ButtonPushed(app, event)
           app.mark_button('4 13',app.b_4_13);
        end

        % Button pushed function: b_4_14
        function b_4_14ButtonPushed(app, event)
           app.mark_button('4 14',app.b_4_14);
        end

        % Button pushed function: b_4_15
        function b_4_15ButtonPushed(app, event)
           app.mark_button('4 15',app.b_4_15);
        end

        % Button pushed function: b_5_1
        function b_5_1ButtonPushed(app, event)
           app.mark_button('5 1',app.b_5_1);
        end

        % Button pushed function: b_5_2
        function b_5_2ButtonPushed(app, event)
           app.mark_button('5 2',app.b_5_2);
        end

        % Button pushed function: b_5_3
        function b_5_3ButtonPushed(app, event)
           app.mark_button('5 3',app.b_5_3);
        end

        % Button pushed function: b_5_4
        function b_5_4ButtonPushed(app, event)
           app.mark_button('5 4',app.b_5_4);
        end

        % Button pushed function: b_5_5
        function b_5_5ButtonPushed(app, event)
           app.mark_button('5 5',app.b_5_5);
        end

        % Button pushed function: b_5_6
        function b_5_6ButtonPushed(app, event)
           app.mark_button('5 6',app.b_5_6);
        end

        % Button pushed function: b_5_7
        function b_5_7ButtonPushed(app, event)
           app.mark_button('5 7',app.b_5_7);
        end

        % Button pushed function: b_5_8
        function b_5_8ButtonPushed(app, event)
           app.mark_button('5 8',app.b_5_8);
        end

        % Button pushed function: b_5_9
        function b_5_9ButtonPushed(app, event)
           app.mark_button('5 9',app.b_5_9);
        end

        % Button pushed function: b_5_10
        function b_5_10ButtonPushed(app, event)
           app.mark_button('5 10',app.b_5_10);
        end

        % Button pushed function: b_5_11
        function b_5_11ButtonPushed(app, event)
           app.mark_button('5 11',app.b_5_11);
        end

        % Button pushed function: b_5_12
        function b_5_12ButtonPushed(app, event)
           app.mark_button('5 12',app.b_5_12);
        end

        % Button pushed function: b_5_13
        function b_5_13ButtonPushed(app, event)
           app.mark_button('5 13',app.b_5_13);
        end

        % Button pushed function: b_5_14
        function b_5_14ButtonPushed(app, event)
           app.mark_button('5 14',app.b_5_14);
        end

        % Button pushed function: b_5_15
        function b_5_15ButtonPushed(app, event)
           app.mark_button('5 15',app.b_5_15);
        end

        % Button pushed function: b_6_1
        function b_6_1ButtonPushed(app, event)
           app.mark_button('6 1',app.b_6_1);
        end

        % Button pushed function: b_6_2
        function b_6_2ButtonPushed(app, event)
           app.mark_button('6 2',app.b_6_2);
        end

        % Button pushed function: b_6_3
        function b_6_3ButtonPushed(app, event)
           app.mark_button('6 3',app.b_6_3);
        end

        % Button pushed function: b_6_4
        function b_6_4ButtonPushed(app, event)
           app.mark_button('6 4',app.b_6_4);
        end

        % Button pushed function: b_6_5
        function b_6_5ButtonPushed(app, event)
           app.mark_button('6 5',app.b_6_5);
        end

        % Button pushed function: b_6_6
        function b_6_6ButtonPushed(app, event)
           app.mark_button('6 6',app.b_6_6);
        end

        % Button pushed function: b_6_7
        function b_6_7ButtonPushed(app, event)
           app.mark_button('6 7',app.b_6_7);
        end

        % Button pushed function: b_6_8
        function b_6_8ButtonPushed(app, event)
           app.mark_button('6 8',app.b_6_8);
        end

        % Button pushed function: b_6_9
        function b_6_9ButtonPushed(app, event)
           app.mark_button('6 9',app.b_6_9);
        end

        % Button pushed function: b_6_10
        function b_6_10ButtonPushed(app, event)
           app.mark_button('6 10',app.b_6_10);
        end

        % Button pushed function: b_6_11
        function b_6_11ButtonPushed(app, event)
           app.mark_button('6 11',app.b_6_11);
        end

        % Button pushed function: b_6_12
        function b_6_12ButtonPushed(app, event)
           app.mark_button('6 12',app.b_6_12);
        end

        % Button pushed function: b_6_13
        function b_6_13ButtonPushed(app, event)
           app.mark_button('6 13',app.b_6_13);
        end

        % Button pushed function: b_6_14
        function b_6_14ButtonPushed(app, event)
           app.mark_button('6 14',app.b_6_14);
        end

        % Button pushed function: b_6_15
        function b_6_15ButtonPushed(app, event)
           app.mark_button('6 15',app.b_6_15);
        end

        % Button pushed function: b_7_1
        function b_7_1ButtonPushed(app, event)
           app.mark_button('7 1',app.b_7_1);
        end

        % Button pushed function: b_7_2
        function b_7_2ButtonPushed(app, event)
           app.mark_button('7 2',app.b_7_2);
        end

        % Button pushed function: b_7_3
        function b_7_3ButtonPushed(app, event)
           app.mark_button('7 3',app.b_7_3);
        end

        % Button pushed function: b_7_4
        function b_7_4ButtonPushed(app, event)
           app.mark_button('7 4',app.b_7_4);
        end

        % Button pushed function: b_7_5
        function b_7_5ButtonPushed(app, event)
           app.mark_button('7 5',app.b_7_5);
        end

        % Button pushed function: b_7_6
        function b_7_6ButtonPushed(app, event)
           app.mark_button('7 6',app.b_7_6);
        end

        % Button pushed function: b_7_7
        function b_7_7ButtonPushed(app, event)
           app.mark_button('7 7',app.b_7_7);
        end

        % Button pushed function: b_7_8
        function b_7_8ButtonPushed(app, event)
           app.mark_button('7 8',app.b_7_8);
        end

        % Button pushed function: b_7_9
        function b_7_9ButtonPushed(app, event)
           app.mark_button('7 9',app.b_7_9);
        end

        % Button pushed function: b_7_10
        function b_7_10ButtonPushed(app, event)
           app.mark_button('7 10',app.b_7_10);
        end

        % Button pushed function: b_7_11
        function b_7_11ButtonPushed(app, event)
           app.mark_button('7 11',app.b_7_11);
        end

        % Button pushed function: b_7_12
        function b_7_12ButtonPushed(app, event)
           app.mark_button('7 12',app.b_7_12);
        end

        % Button pushed function: b_7_13
        function b_7_13ButtonPushed(app, event)
           app.mark_button('7 13',app.b_7_13);
        end

        % Button pushed function: b_7_14
        function b_7_14ButtonPushed(app, event)
           app.mark_button('7 14',app.b_7_14);
        end

        % Button pushed function: b_7_15
        function b_7_15ButtonPushed(app, event)
           app.mark_button('7 15',app.b_7_15);
        end

        % Button pushed function: b_8_1
        function b_8_1ButtonPushed(app, event)
           app.mark_button('8 1',app.b_8_1);
        end

        % Button pushed function: b_8_2
        function b_8_2ButtonPushed(app, event)
           app.mark_button('8 2',app.b_8_2);
        end

        % Button pushed function: b_8_3
        function b_8_3ButtonPushed(app, event)
           app.mark_button('8 3',app.b_8_3);
        end

        % Button pushed function: b_8_4
        function b_8_4ButtonPushed(app, event)
           app.mark_button('8 4',app.b_8_4);
        end

        % Button pushed function: b_8_5
        function b_8_5ButtonPushed(app, event)
           app.mark_button('8 5',app.b_8_5);
        end

        % Button pushed function: b_8_6
        function b_8_6ButtonPushed(app, event)
           app.mark_button('8 6',app.b_8_6);
        end

        % Button pushed function: b_8_7
        function b_8_7ButtonPushed(app, event)
           app.mark_button('8 7',app.b_8_7);
        end

        % Button pushed function: b_8_8
        function b_8_8ButtonPushed(app, event)
           app.mark_button('8 8',app.b_8_8);
        end

        % Button pushed function: b_8_9
        function b_8_9ButtonPushed(app, event)
           app.mark_button('8 9',app.b_8_9);
        end

        % Button pushed function: b_8_10
        function b_8_10ButtonPushed(app, event)
           app.mark_button('8 10',app.b_8_10);
        end

        % Button pushed function: b_8_11
        function b_8_11ButtonPushed(app, event)
           app.mark_button('8 11',app.b_8_11);
        end

        % Button pushed function: b_8_12
        function b_8_12ButtonPushed(app, event)
           app.mark_button('8 12',app.b_8_12);
        end

        % Button pushed function: b_8_13
        function b_8_13ButtonPushed(app, event)
           app.mark_button('8 13',app.b_8_13);
        end

        % Button pushed function: b_8_14
        function b_8_14ButtonPushed(app, event)
           app.mark_button('8 14',app.b_8_14);
        end

        % Button pushed function: b_8_15
        function b_8_15ButtonPushed(app, event)
           app.mark_button('8 15',app.b_8_15);
        end

        % Button pushed function: b_9_1
        function b_9_1ButtonPushed(app, event)
           app.mark_button('9 1',app.b_9_1);
        end

        % Button pushed function: b_9_2
        function b_9_2ButtonPushed(app, event)
           app.mark_button('9 2',app.b_9_2);
        end

        % Button pushed function: b_9_3
        function b_9_3ButtonPushed(app, event)
           app.mark_button('9 3',app.b_9_3);
        end

        % Button pushed function: b_9_4
        function b_9_4ButtonPushed(app, event)
           app.mark_button('9 4',app.b_9_4);
        end

        % Button pushed function: b_9_5
        function b_9_5ButtonPushed(app, event)
           app.mark_button('9 5',app.b_9_5);
        end

        % Button pushed function: b_9_6
        function b_9_6ButtonPushed(app, event)
           app.mark_button('9 6',app.b_9_6);
        end

        % Button pushed function: b_9_7
        function b_9_7ButtonPushed(app, event)
           app.mark_button('9 7',app.b_9_7);
        end

        % Button pushed function: b_9_8
        function b_9_8ButtonPushed(app, event)
           app.mark_button('9 8',app.b_9_8);
        end

        % Button pushed function: b_9_9
        function b_9_9ButtonPushed(app, event)
           app.mark_button('9 9',app.b_9_9);
        end

        % Button pushed function: b_9_10
        function b_9_10ButtonPushed(app, event)
           app.mark_button('9 10',app.b_9_10);
        end

        % Button pushed function: b_9_11
        function b_9_11ButtonPushed(app, event)
           app.mark_button('9 11',app.b_9_11);
        end

        % Button pushed function: b_9_12
        function b_9_12ButtonPushed(app, event)
           app.mark_button('9 12',app.b_9_12);
        end

        % Button pushed function: b_9_13
        function b_9_13ButtonPushed(app, event)
           app.mark_button('9 13',app.b_9_13);
        end

        % Button pushed function: b_9_14
        function b_9_14ButtonPushed(app, event)
           app.mark_button('9 14',app.b_9_14);
        end

        % Button pushed function: b_9_15
        function b_9_15ButtonPushed(app, event)
           app.mark_button('9 15',app.b_9_15);
        end

        % Button pushed function: b_10_1
        function b_10_1ButtonPushed(app, event)
           app.mark_button('10 1',app.b_10_1);
        end

        % Button pushed function: b_10_2
        function b_10_2ButtonPushed(app, event)
           app.mark_button('10 2',app.b_10_2);
        end

        % Button pushed function: b_10_3
        function b_10_3ButtonPushed(app, event)
           app.mark_button('10 3',app.b_10_3);
        end

        % Button pushed function: b_10_4
        function b_10_4ButtonPushed(app, event)
           app.mark_button('10 4',app.b_10_4);
        end

        % Button pushed function: b_10_5
        function b_10_5ButtonPushed(app, event)
           app.mark_button('10 5',app.b_10_5);
        end

        % Button pushed function: b_10_6
        function b_10_6ButtonPushed(app, event)
           app.mark_button('10 6',app.b_10_6);
        end

        % Button pushed function: b_10_7
        function b_10_7ButtonPushed(app, event)
           app.mark_button('10 7',app.b_10_7);
        end

        % Button pushed function: b_10_8
        function b_10_8ButtonPushed(app, event)
           app.mark_button('10 8',app.b_10_8);
        end

        % Button pushed function: b_10_9
        function b_10_9ButtonPushed(app, event)
           app.mark_button('10 9',app.b_10_9);
        end

        % Button pushed function: b_10_10
        function b_10_10ButtonPushed(app, event)
           app.mark_button('10 10',app.b_10_10);
        end

        % Button pushed function: b_10_11
        function b_10_11ButtonPushed(app, event)
           app.mark_button('10 11',app.b_10_11);
        end

        % Button pushed function: b_10_12
        function b_10_12ButtonPushed(app, event)
           app.mark_button('10 12',app.b_10_12);
        end

        % Button pushed function: b_10_13
        function b_10_13ButtonPushed(app, event)
           app.mark_button('10 13',app.b_10_13);
        end

        % Button pushed function: b_10_14
        function b_10_14ButtonPushed(app, event)
           app.mark_button('10 14',app.b_10_14);
        end

        % Button pushed function: b_10_15
        function b_10_15ButtonPushed(app, event)
           app.mark_button('10 15',app.b_10_15);
        end

        % Button pushed function: b_11_1
        function b_11_1ButtonPushed(app, event)
           app.mark_button('11 1',app.b_11_1);
        end

        % Button pushed function: b_11_2
        function b_11_2ButtonPushed(app, event)
           app.mark_button('11 2',app.b_11_2);
        end

        % Button pushed function: b_11_3
        function b_11_3ButtonPushed(app, event)
           app.mark_button('11 3',app.b_11_3);
        end

        % Button pushed function: b_11_4
        function b_11_4ButtonPushed(app, event)
           app.mark_button('11 4',app.b_11_4);
        end

        % Button pushed function: b_11_5
        function b_11_5ButtonPushed(app, event)
           app.mark_button('11 5',app.b_11_5);
        end

        % Button pushed function: b_11_6
        function b_11_6ButtonPushed(app, event)
           app.mark_button('11 6',app.b_11_6);
        end

        % Button pushed function: b_11_7
        function b_11_7ButtonPushed(app, event)
           app.mark_button('11 7',app.b_11_7);
        end

        % Button pushed function: b_11_8
        function b_11_8ButtonPushed(app, event)
           app.mark_button('11 8',app.b_11_8);
        end

        % Button pushed function: b_11_9
        function b_11_9ButtonPushed(app, event)
           app.mark_button('11 9',app.b_11_9);
        end

        % Button pushed function: b_11_10
        function b_11_10ButtonPushed(app, event)
           app.mark_button('11 10',app.b_11_10);
        end

        % Button pushed function: b_11_11
        function b_11_11ButtonPushed(app, event)
           app.mark_button('11 11',app.b_11_11);
        end

        % Button pushed function: b_11_12
        function b_11_12ButtonPushed(app, event)
           app.mark_button('11 12',app.b_11_12);
        end

        % Button pushed function: b_11_13
        function b_11_13ButtonPushed(app, event)
           app.mark_button('11 13',app.b_11_13);
        end

        % Button pushed function: b_11_14
        function b_11_14ButtonPushed(app, event)
           app.mark_button('11 14',app.b_11_14);
        end

        % Button pushed function: b_11_15
        function b_11_15ButtonPushed(app, event)
           app.mark_button('11 15',app.b_11_15);
        end

        % Button pushed function: b_12_1
        function b_12_1ButtonPushed(app, event)
           app.mark_button('12 1',app.b_12_1);
        end

        % Button pushed function: b_12_2
        function b_12_2ButtonPushed(app, event)
            app.mark_button('12 2',app.b_12_2);
        end

        % Button pushed function: b_12_3
        function b_12_3ButtonPushed(app, event)
            app.mark_button('12 3',app.b_12_3);
        end

        % Button pushed function: b_12_4
        function b_12_4ButtonPushed(app, event)
            app.mark_button('12 4',app.b_12_4);
        end

        % Button pushed function: b_12_5
        function b_12_5ButtonPushed(app, event)
            app.mark_button('12 5',app.b_12_5);
        end

        % Button pushed function: b_12_6
        function b_12_6ButtonPushed(app, event)
            app.mark_button('12 6',app.b_12_6);
        end

        % Button pushed function: b_12_7
        function b_12_7ButtonPushed(app, event)
            app.mark_button('12 7',app.b_12_7);
        end

        % Button pushed function: b_12_8
        function b_12_8ButtonPushed(app, event)
            app.mark_button('12 8',app.b_12_8);
        end

        % Button pushed function: b_12_9
        function b_12_9ButtonPushed(app, event)
            app.mark_button('12 9',app.b_12_9);
        end

        % Button pushed function: b_12_10
        function b_12_10ButtonPushed(app, event)
            app.mark_button('12 10',app.b_12_10);
        end

        % Button pushed function: b_12_11
        function b_12_11ButtonPushed(app, event)
            app.mark_button('12 11',app.b_12_11);
        end

        % Button pushed function: b_12_12
        function b_12_12ButtonPushed(app, event)
            app.mark_button('12 12',app.b_12_12);
        end

        % Button pushed function: b_12_13
        function b_12_13ButtonPushed(app, event)
            app.mark_button('12 13',app.b_12_13);
        end

        % Button pushed function: b_12_14
        function b_12_14ButtonPushed(app, event)
            app.mark_button('12 14',app.b_12_14);
        end

        % Button pushed function: b_12_15
        function b_12_15ButtonPushed(app, event)
            app.mark_button('12 15',app.b_12_15);
        end

        % Button pushed function: b_13_1
        function b_13_1ButtonPushed(app, event)
            app.mark_button('13 1',app.b_13_1);
        end

        % Button pushed function: b_13_2
        function b_13_2ButtonPushed(app, event)
            app.mark_button('13 2',app.b_13_2);
        end

        % Button pushed function: b_13_3
        function b_13_3ButtonPushed(app, event)
            app.mark_button('13 3',app.b_13_3);
        end

        % Button pushed function: b_13_4
        function b_13_4ButtonPushed(app, event)
            app.mark_button('13 4',app.b_13_4);
        end

        % Button pushed function: b_13_5
        function b_13_5ButtonPushed(app, event)
            app.mark_button('13 5',app.b_13_5);
        end

        % Button pushed function: b_13_6
        function b_13_6ButtonPushed(app, event)
            app.mark_button('13 6',app.b_13_6);
        end

        % Button pushed function: b_13_7
        function b_13_7ButtonPushed(app, event)
            app.mark_button('13 7',app.b_13_7);
        end

        % Button pushed function: b_13_8
        function b_13_8ButtonPushed(app, event)
            app.mark_button('13 8',app.b_13_8);
        end

        % Button pushed function: b_13_9
        function b_13_9ButtonPushed(app, event)
            app.mark_button('13 9',app.b_13_9);
        end

        % Button pushed function: b_13_10
        function b_13_10ButtonPushed(app, event)
            app.mark_button('13 10',app.b_13_10);
        end

        % Button pushed function: b_13_11
        function b_13_11ButtonPushed(app, event)
            app.mark_button('13 11',app.b_13_11);
        end

        % Button pushed function: b_13_12
        function b_13_12ButtonPushed(app, event)
            app.mark_button('13 12',app.b_13_12);
        end

        % Button pushed function: b_13_13
        function b_13_13ButtonPushed(app, event)
            app.mark_button('13 13',app.b_13_13);
        end

        % Button pushed function: b_13_14
        function b_13_14ButtonPushed(app, event)
            app.mark_button('13 14',app.b_13_14);
        end

        % Button pushed function: b_13_15
        function b_13_15ButtonPushed(app, event)
            app.mark_button('13 15',app.b_13_15);
        end

        % Button pushed function: b_14_1
        function b_14_1ButtonPushed(app, event)
            app.mark_button('14 1',app.b_14_1);
        end

        % Button pushed function: b_14_2
        function b_14_2ButtonPushed(app, event)
            app.mark_button('14 2',app.b_14_2);
        end

        % Button pushed function: b_14_3
        function b_14_3ButtonPushed(app, event)
            app.mark_button('14 3',app.b_14_3);
        end

        % Button pushed function: b_14_4
        function b_14_4ButtonPushed(app, event)
            app.mark_button('14 4',app.b_14_4);
        end

        % Button pushed function: b_14_5
        function b_14_5ButtonPushed(app, event)
            app.mark_button('14 5',app.b_14_5);
        end

        % Button pushed function: b_14_6
        function b_14_6ButtonPushed(app, event)
            app.mark_button('14 6',app.b_14_6);
        end

        % Button pushed function: b_14_7
        function b_14_7ButtonPushed(app, event)
            app.mark_button('14 7',app.b_14_7);
        end

        % Button pushed function: b_14_8
        function b_14_8ButtonPushed(app, event)
            app.mark_button('14 8',app.b_14_8);
        end

        % Button pushed function: b_14_9
        function b_14_9ButtonPushed(app, event)
            app.mark_button('14 9',app.b_14_9);
        end

        % Button pushed function: b_14_10
        function b_14_10ButtonPushed(app, event)
            app.mark_button('14 10',app.b_14_10);
        end

        % Button pushed function: b_14_11
        function b_14_11ButtonPushed(app, event)
            app.mark_button('14 11',app.b_14_11);
        end

        % Button pushed function: b_14_12
        function b_14_12ButtonPushed(app, event)
            app.mark_button('14 12',app.b_14_12);
        end

        % Button pushed function: b_14_13
        function b_14_13ButtonPushed(app, event)
            app.mark_button('14 13',app.b_14_13);
        end

        % Button pushed function: b_14_14
        function b_14_14ButtonPushed(app, event)
            app.mark_button('14 14',app.b_14_14);
        end

        % Button pushed function: b_14_15
        function b_14_15ButtonPushed(app, event)
            app.mark_button('14 15',app.b_14_15);
        end

        % Button pushed function: b_15_1
        function b_15_1ButtonPushed(app, event)
            app.mark_button('15 1',app.b_15_1);
        end

        % Button pushed function: b_15_2
        function b_15_2ButtonPushed(app, event)
            app.mark_button('15 2',app.b_15_2);
        end

        % Button pushed function: b_15_3
        function b_15_3ButtonPushed(app, event)
            app.mark_button('15 3',app.b_15_3);
        end

        % Button pushed function: b_15_4
        function b_15_4ButtonPushed(app, event)
            app.mark_button('15 4',app.b_15_4);
        end

        % Button pushed function: b_15_5
        function b_15_5ButtonPushed(app, event)
            app.mark_button('15 5',app.b_15_5);
        end

        % Button pushed function: b_15_6
        function b_15_6ButtonPushed(app, event)
            app.mark_button('15 6',app.b_15_6);
        end

        % Button pushed function: b_15_7
        function b_15_7ButtonPushed(app, event)
            app.mark_button('15 7',app.b_15_7);
        end

        % Button pushed function: b_15_8
        function b_15_8ButtonPushed(app, event)
            app.mark_button('15 8',app.b_15_8);
        end

        % Button pushed function: b_15_9
        function b_15_9ButtonPushed(app, event)
            app.mark_button('15 9',app.b_15_9);
        end

        % Button pushed function: b_15_10
        function b_15_10ButtonPushed(app, event)
            app.mark_button('15 10',app.b_15_10);
        end

        % Button pushed function: b_15_11
        function b_15_11ButtonPushed(app, event)
            app.mark_button('15 11',app.b_15_11);
        end

        % Button pushed function: b_15_12
        function b_15_12ButtonPushed(app, event)
            app.mark_button('15 12',app.b_15_12);
        end

        % Button pushed function: b_15_13
        function b_15_13ButtonPushed(app, event)
            app.mark_button('15 13',app.b_15_13);
        end

        % Button pushed function: b_15_14
        function b_15_14ButtonPushed(app, event)
            app.mark_button('15 14',app.b_15_14);
        end

        % Button pushed function: b_15_15
        function b_15_15ButtonPushed(app, event)
            app.mark_button('15 15',app.b_15_15);
        end

        % Button pushed function: ClearStartButton
        function ClearStartButtonPushed(app, event)
            app.clear_button( app.start_checkbox, app.StartCheckBox)
            app.start_checkbox = [];
        end

        % Button pushed function: ClearCiljButton
        function ClearCiljButtonPushed(app, event)
            app.clear_button(app.cilj_checkbox, app.CiljCheckBox)
            app.cilj_checkbox = [];
        end

        % Button pushed function: ClearPreprekaButton
        function ClearPreprekaButtonPushed(app, event)
            app.clear_button(app.prepreke_checkboxes, app.PreprekaCheckBox)
            app.prepreke_checkboxes = [];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(length(app.start_checkbox) == 1 &&  length(app.cilj_checkbox) == 1 && app.calculate == 1 )
                            
                C =strsplit(app.start_checkbox(1).Tag, ' ');
                my_start.i = str2num(cell2mat(C(1)));
                my_start.j = str2num(cell2mat(C(2))); 
                
                C =strsplit(app.cilj_checkbox(1).Tag, ' ');
                my_cilj.i = str2num(cell2mat(C(1)));
                my_cilj.j = str2num(cell2mat(C(2))); 
                
                prepreke = [];
                if length(app.prepreke_checkboxes) > 0
                    for ind = 1 : length(app.prepreke_checkboxes)
                       
                       C =strsplit(app.prepreke_checkboxes(ind).Tag, ' '); 
                       prepreka.i = str2num(cell2mat(C(1)));
                       prepreka.j = str2num(cell2mat(C(2)));
                       prepreke = [prepreke, prepreka];
                    end
                end
               
                
                best_path = GA_safe_path(app.generationSlider.Value, app.populationSlider.Value, app.epsilonSlider.Value, app.lambdaSlider.Value, my_start, my_cilj, prepreke, app.crossoverSlider.Value, app.mutationSlider.Value);
                
                if(isempty(best_path))
                    msgbox('Cannot find a path', 'Error','error')   
                    return;
                end
                
                msgbox('Operation Completed','Success')
                
                best_path = best_path(2:end-1);
                for path_ind = 1:length(best_path)
                    my_name =  strcat('app.b_',num2str(best_path(path_ind).i),'_',num2str(best_path(path_ind).j));
                    my_button = eval(my_name);
                    app.my_best_path = [app.my_best_path, my_button];
                    my_button.BackgroundColor = 'black';
                    my_button.Enable = false;
                    app.calculate = 0;
                    
                end
            end
        end

        % Value changed function: populationSlider
        function populationSliderValueChanged(app, event)
            value = app.populationSlider.Value;
            
            app.populationSlider.Value = double(round(value));
        end

        % Value changed function: generationSlider
        function generationSliderValueChanged(app, event)
            value = app.generationSlider.Value;
            
            app.generationSlider.Value =  double(round(value));
        end

        % Button pushed function: ClearPathButton
        function ClearPathButtonPushed(app, event)
            app.clear_button(app.my_best_path, app.PreprekaCheckBox)
            app.my_best_path = [];
            app.calculate = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.StartCheckBox = uicheckbox(app.Tab);
            app.StartCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartCheckBoxValueChanged, true);
            app.StartCheckBox.Text = 'Start';
            app.StartCheckBox.Value = true;
            app.CiljCheckBox = uicheckbox(app.Tab);
            app.CiljCheckBox.ValueChangedFcn = createCallbackFcn(app, @CiljCheckBoxValueChanged, true);
            app.CiljCheckBox.Enable = 'off';
            app.CiljCheckBox.Text = 'Cilj';
            app.PreprekaCheckBox = uicheckbox(app.Tab);
            app.PreprekaCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreprekaCheckBoxValueChanged, true);
            app.PreprekaCheckBox.Enable = 'off';
            app.PreprekaCheckBox.Text = 'Prepreka';
            app.b_1_1 = uibutton(app.Tab, 'push');
            app.b_1_1.ButtonPushedFcn = createCallbackFcn(app, @b_1_1ButtonPushed, true);
            app.b_1_1.Text = '';
            app.b_1_2 = uibutton(app.Tab, 'push');
            app.b_1_2.ButtonPushedFcn = createCallbackFcn(app, @b_1_2ButtonPushed, true);
            app.b_1_2.Text = '';
            app.b_1_3 = uibutton(app.Tab, 'push');
            app.b_1_3.ButtonPushedFcn = createCallbackFcn(app, @b_1_3ButtonPushed, true);
            app.b_1_3.Text = '';
            app.b_1_4 = uibutton(app.Tab, 'push');
            app.b_1_4.ButtonPushedFcn = createCallbackFcn(app, @b_1_4ButtonPushed, true);
            app.b_1_4.Text = '';
            app.b_1_5 = uibutton(app.Tab, 'push');
            app.b_1_5.ButtonPushedFcn = createCallbackFcn(app, @b_1_5ButtonPushed, true);
            app.b_1_5.Text = '';
            app.b_1_6 = uibutton(app.Tab, 'push');
            app.b_1_6.ButtonPushedFcn = createCallbackFcn(app, @b_1_6ButtonPushed, true);
            app.b_1_6.Text = '';
            app.b_1_7 = uibutton(app.Tab, 'push');
            app.b_1_7.ButtonPushedFcn = createCallbackFcn(app, @b_1_7ButtonPushed, true);
            app.b_1_7.Text = '';
            app.b_1_8 = uibutton(app.Tab, 'push');
            app.b_1_8.ButtonPushedFcn = createCallbackFcn(app, @b_1_8ButtonPushed, true);
            app.b_1_8.Text = '';
            app.b_1_9 = uibutton(app.Tab, 'push');
            app.b_1_9.ButtonPushedFcn = createCallbackFcn(app, @b_1_9ButtonPushed, true);
            app.b_1_9.Text = '';
            app.b_1_10 = uibutton(app.Tab, 'push');
            app.b_1_10.ButtonPushedFcn = createCallbackFcn(app, @b_1_10ButtonPushed, true);
            app.b_1_10.Text = '';
            app.b_1_11 = uibutton(app.Tab, 'push');
            app.b_1_11.ButtonPushedFcn = createCallbackFcn(app, @b_1_11ButtonPushed, true);
            app.b_1_11.Text = '';
            app.b_1_12 = uibutton(app.Tab, 'push');
            app.b_1_12.ButtonPushedFcn = createCallbackFcn(app, @b_1_12ButtonPushed, true);
            app.b_1_12.Text = '';
            app.b_1_13 = uibutton(app.Tab, 'push');
            app.b_1_13.ButtonPushedFcn = createCallbackFcn(app, @b_1_13ButtonPushed, true);
            app.b_1_13.Text = '';
            app.b_1_14 = uibutton(app.Tab, 'push');
            app.b_1_14.ButtonPushedFcn = createCallbackFcn(app, @b_1_14ButtonPushed, true);
            app.b_1_14.Text = '';
            app.b_1_15 = uibutton(app.Tab, 'push');
            app.b_1_15.ButtonPushedFcn = createCallbackFcn(app, @b_1_15ButtonPushed, true);
            app.b_1_15.Text = '';
            app.b_2_1 = uibutton(app.Tab, 'push');
            app.b_2_1.ButtonPushedFcn = createCallbackFcn(app, @b_2_1ButtonPushed, true);
            app.b_2_1.Text = '';
            app.b_2_2 = uibutton(app.Tab, 'push');
            app.b_2_2.ButtonPushedFcn = createCallbackFcn(app, @b_2_2ButtonPushed, true);
            app.b_2_2.Text = '';
            app.b_2_3 = uibutton(app.Tab, 'push');
            app.b_2_3.ButtonPushedFcn = createCallbackFcn(app, @b_2_3ButtonPushed, true);
            app.b_2_3.Text = '';
            app.b_2_4 = uibutton(app.Tab, 'push');
            app.b_2_4.ButtonPushedFcn = createCallbackFcn(app, @b_2_4ButtonPushed, true);
            app.b_2_4.Text = '';
            app.b_2_5 = uibutton(app.Tab, 'push');
            app.b_2_5.ButtonPushedFcn = createCallbackFcn(app, @b_2_5ButtonPushed, true);
            app.b_2_5.Text = '';
            app.b_2_6 = uibutton(app.Tab, 'push');
            app.b_2_6.ButtonPushedFcn = createCallbackFcn(app, @b_2_6ButtonPushed, true);
            app.b_2_6.Text = '';
            app.b_2_7 = uibutton(app.Tab, 'push');
            app.b_2_7.ButtonPushedFcn = createCallbackFcn(app, @b_2_7ButtonPushed, true);
            app.b_2_7.Text = '';
            app.b_2_8 = uibutton(app.Tab, 'push');
            app.b_2_8.ButtonPushedFcn = createCallbackFcn(app, @b_2_8ButtonPushed, true);
            app.b_2_8.Text = '';
            app.b_2_9 = uibutton(app.Tab, 'push');
            app.b_2_9.ButtonPushedFcn = createCallbackFcn(app, @b_2_9ButtonPushed, true);
            app.b_2_9.Text = '';
            app.b_2_10 = uibutton(app.Tab, 'push');
            app.b_2_10.ButtonPushedFcn = createCallbackFcn(app, @b_2_10ButtonPushed, true);
            app.b_2_10.Text = '';
            app.b_2_11 = uibutton(app.Tab, 'push');
            app.b_2_11.ButtonPushedFcn = createCallbackFcn(app, @b_2_11ButtonPushed, true);
            app.b_2_11.Text = '';
            app.b_2_12 = uibutton(app.Tab, 'push');
            app.b_2_12.ButtonPushedFcn = createCallbackFcn(app, @b_2_12ButtonPushed, true);
            app.b_2_12.Text = '';
            app.b_2_13 = uibutton(app.Tab, 'push');
            app.b_2_13.ButtonPushedFcn = createCallbackFcn(app, @b_2_13ButtonPushed, true);
            app.b_2_13.Text = '';
            app.b_2_14 = uibutton(app.Tab, 'push');
            app.b_2_14.ButtonPushedFcn = createCallbackFcn(app, @b_2_14ButtonPushed, true);
            app.b_2_14.Text = '';
            app.b_2_15 = uibutton(app.Tab, 'push');
            app.b_2_15.ButtonPushedFcn = createCallbackFcn(app, @b_2_15ButtonPushed, true);
            app.b_2_15.Text = '';
            app.b_3_1 = uibutton(app.Tab, 'push');
            app.b_3_1.ButtonPushedFcn = createCallbackFcn(app, @b_3_1ButtonPushed, true);
            app.b_3_1.Text = '';
            app.b_3_2 = uibutton(app.Tab, 'push');
            app.b_3_2.ButtonPushedFcn = createCallbackFcn(app, @b_3_2ButtonPushed, true);
            app.b_3_2.Text = '';
            app.b_3_3 = uibutton(app.Tab, 'push');
            app.b_3_3.ButtonPushedFcn = createCallbackFcn(app, @b_3_3ButtonPushed, true);
            app.b_3_3.Text = '';
            app.b_3_4 = uibutton(app.Tab, 'push');
            app.b_3_4.ButtonPushedFcn = createCallbackFcn(app, @b_3_4ButtonPushed, true);
            app.b_3_4.Text = '';
            app.b_3_5 = uibutton(app.Tab, 'push');
            app.b_3_5.ButtonPushedFcn = createCallbackFcn(app, @b_3_5ButtonPushed, true);
            app.b_3_5.Text = '';
            app.b_3_6 = uibutton(app.Tab, 'push');
            app.b_3_6.ButtonPushedFcn = createCallbackFcn(app, @b_3_6ButtonPushed, true);
            app.b_3_6.Text = '';
            app.b_3_7 = uibutton(app.Tab, 'push');
            app.b_3_7.ButtonPushedFcn = createCallbackFcn(app, @b_3_7ButtonPushed, true);
            app.b_3_7.Text = '';
            app.b_3_8 = uibutton(app.Tab, 'push');
            app.b_3_8.ButtonPushedFcn = createCallbackFcn(app, @b_3_8ButtonPushed, true);
            app.b_3_8.Text = '';
            app.b_3_9 = uibutton(app.Tab, 'push');
            app.b_3_9.ButtonPushedFcn = createCallbackFcn(app, @b_3_9ButtonPushed, true);
            app.b_3_9.Text = '';
            app.b_3_10 = uibutton(app.Tab, 'push');
            app.b_3_10.ButtonPushedFcn = createCallbackFcn(app, @b_3_10ButtonPushed, true);
            app.b_3_10.Text = '';
            app.b_3_11 = uibutton(app.Tab, 'push');
            app.b_3_11.ButtonPushedFcn = createCallbackFcn(app, @b_3_11ButtonPushed, true);
            app.b_3_11.Text = '';
            app.b_3_12 = uibutton(app.Tab, 'push');
            app.b_3_12.ButtonPushedFcn = createCallbackFcn(app, @b_3_12ButtonPushed, true);
            app.b_3_12.Text = '';
            app.b_3_13 = uibutton(app.Tab, 'push');
            app.b_3_13.ButtonPushedFcn = createCallbackFcn(app, @b_3_13ButtonPushed, true);
            app.b_3_13.Text = '';
            app.b_3_14 = uibutton(app.Tab, 'push');
            app.b_3_14.ButtonPushedFcn = createCallbackFcn(app, @b_3_14ButtonPushed, true);
            app.b_3_14.Text = '';
            app.b_3_15 = uibutton(app.Tab, 'push');
            app.b_3_15.ButtonPushedFcn = createCallbackFcn(app, @b_3_15ButtonPushed, true);
            app.b_3_15.Text = '';
            app.b_4_1 = uibutton(app.Tab, 'push');
            app.b_4_1.ButtonPushedFcn = createCallbackFcn(app, @b_4_1ButtonPushed, true);
            app.b_4_1.Text = '';
            app.b_4_2 = uibutton(app.Tab, 'push');
            app.b_4_2.ButtonPushedFcn = createCallbackFcn(app, @b_4_2ButtonPushed, true);
            app.b_4_2.Text = '';
            app.b_4_3 = uibutton(app.Tab, 'push');
            app.b_4_3.ButtonPushedFcn = createCallbackFcn(app, @b_4_3ButtonPushed, true);
            app.b_4_3.Text = '';
            app.b_4_4 = uibutton(app.Tab, 'push');
            app.b_4_4.ButtonPushedFcn = createCallbackFcn(app, @b_4_4ButtonPushed, true);
            app.b_4_4.Text = '';
            app.b_4_5 = uibutton(app.Tab, 'push');
            app.b_4_5.ButtonPushedFcn = createCallbackFcn(app, @b_4_5ButtonPushed, true);
            app.b_4_5.Text = '';
            app.b_4_6 = uibutton(app.Tab, 'push');
            app.b_4_6.ButtonPushedFcn = createCallbackFcn(app, @b_4_6ButtonPushed, true);
            app.b_4_6.Text = '';
            app.b_4_7 = uibutton(app.Tab, 'push');
            app.b_4_7.ButtonPushedFcn = createCallbackFcn(app, @b_4_7ButtonPushed, true);
            app.b_4_7.Text = '';
            app.b_4_8 = uibutton(app.Tab, 'push');
            app.b_4_8.ButtonPushedFcn = createCallbackFcn(app, @b_4_8ButtonPushed, true);
            app.b_4_8.Text = '';
            app.b_4_9 = uibutton(app.Tab, 'push');
            app.b_4_9.ButtonPushedFcn = createCallbackFcn(app, @b_4_9ButtonPushed, true);
            app.b_4_9.Text = '';
            app.b_4_10 = uibutton(app.Tab, 'push');
            app.b_4_10.ButtonPushedFcn = createCallbackFcn(app, @b_4_10ButtonPushed, true);
            app.b_4_10.Text = '';
            app.b_4_11 = uibutton(app.Tab, 'push');
            app.b_4_11.ButtonPushedFcn = createCallbackFcn(app, @b_4_11ButtonPushed, true);
            app.b_4_11.Text = '';
            app.b_4_12 = uibutton(app.Tab, 'push');
            app.b_4_12.ButtonPushedFcn = createCallbackFcn(app, @b_4_12ButtonPushed, true);
            app.b_4_12.Text = '';
            app.b_4_13 = uibutton(app.Tab, 'push');
            app.b_4_13.ButtonPushedFcn = createCallbackFcn(app, @b_4_13ButtonPushed, true);
            app.b_4_13.Text = '';
            app.b_4_14 = uibutton(app.Tab, 'push');
            app.b_4_14.ButtonPushedFcn = createCallbackFcn(app, @b_4_14ButtonPushed, true);
            app.b_4_14.Text = '';
            app.b_4_15 = uibutton(app.Tab, 'push');
            app.b_4_15.ButtonPushedFcn = createCallbackFcn(app, @b_4_15ButtonPushed, true);
            app.b_4_15.Text = '';
            app.b_5_1 = uibutton(app.Tab, 'push');
            app.b_5_1.ButtonPushedFcn = createCallbackFcn(app, @b_5_1ButtonPushed, true);
            app.b_5_1.Text = '';
            app.b_5_2 = uibutton(app.Tab, 'push');
            app.b_5_2.ButtonPushedFcn = createCallbackFcn(app, @b_5_2ButtonPushed, true);
            app.b_5_2.Text = '';
            app.b_5_3 = uibutton(app.Tab, 'push');
            app.b_5_3.ButtonPushedFcn = createCallbackFcn(app, @b_5_3ButtonPushed, true);
            app.b_5_3.Text = '';
            app.b_5_4 = uibutton(app.Tab, 'push');
            app.b_5_4.ButtonPushedFcn = createCallbackFcn(app, @b_5_4ButtonPushed, true);
            app.b_5_4.Text = '';
            app.b_5_5 = uibutton(app.Tab, 'push');
            app.b_5_5.ButtonPushedFcn = createCallbackFcn(app, @b_5_5ButtonPushed, true);
            app.b_5_5.Text = '';
            app.b_5_6 = uibutton(app.Tab, 'push');
            app.b_5_6.ButtonPushedFcn = createCallbackFcn(app, @b_5_6ButtonPushed, true);
            app.b_5_6.Text = '';
            app.b_5_7 = uibutton(app.Tab, 'push');
            app.b_5_7.ButtonPushedFcn = createCallbackFcn(app, @b_5_7ButtonPushed, true);
            app.b_5_7.Text = '';
            app.b_5_8 = uibutton(app.Tab, 'push');
            app.b_5_8.ButtonPushedFcn = createCallbackFcn(app, @b_5_8ButtonPushed, true);
            app.b_5_8.Text = '';
            app.b_5_9 = uibutton(app.Tab, 'push');
            app.b_5_9.ButtonPushedFcn = createCallbackFcn(app, @b_5_9ButtonPushed, true);
            app.b_5_9.Text = '';
            app.b_5_10 = uibutton(app.Tab, 'push');
            app.b_5_10.ButtonPushedFcn = createCallbackFcn(app, @b_5_10ButtonPushed, true);
            app.b_5_10.Text = '';
            app.b_5_11 = uibutton(app.Tab, 'push');
            app.b_5_11.ButtonPushedFcn = createCallbackFcn(app, @b_5_11ButtonPushed, true);
            app.b_5_11.Text = '';
            app.b_5_12 = uibutton(app.Tab, 'push');
            app.b_5_12.ButtonPushedFcn = createCallbackFcn(app, @b_5_12ButtonPushed, true);
            app.b_5_12.Text = '';
            app.b_5_13 = uibutton(app.Tab, 'push');
            app.b_5_13.ButtonPushedFcn = createCallbackFcn(app, @b_5_13ButtonPushed, true);
            app.b_5_13.Text = '';
            app.b_5_14 = uibutton(app.Tab, 'push');
            app.b_5_14.ButtonPushedFcn = createCallbackFcn(app, @b_5_14ButtonPushed, true);
            app.b_5_14.Text = '';
            app.b_5_15 = uibutton(app.Tab, 'push');
            app.b_5_15.ButtonPushedFcn = createCallbackFcn(app, @b_5_15ButtonPushed, true);
            app.b_5_15.Text = '';
            app.b_6_1 = uibutton(app.Tab, 'push');
            app.b_6_1.ButtonPushedFcn = createCallbackFcn(app, @b_6_1ButtonPushed, true);
            app.b_6_1.Text = '';
            app.b_6_2 = uibutton(app.Tab, 'push');
            app.b_6_2.ButtonPushedFcn = createCallbackFcn(app, @b_6_2ButtonPushed, true);
            app.b_6_2.Text = '';
            app.b_6_3 = uibutton(app.Tab, 'push');
            app.b_6_3.ButtonPushedFcn = createCallbackFcn(app, @b_6_3ButtonPushed, true);
            app.b_6_3.Text = '';
            app.b_6_4 = uibutton(app.Tab, 'push');
            app.b_6_4.ButtonPushedFcn = createCallbackFcn(app, @b_6_4ButtonPushed, true);
            app.b_6_4.Text = '';
            app.b_6_5 = uibutton(app.Tab, 'push');
            app.b_6_5.ButtonPushedFcn = createCallbackFcn(app, @b_6_5ButtonPushed, true);
            app.b_6_5.Text = '';
            app.b_6_6 = uibutton(app.Tab, 'push');
            app.b_6_6.ButtonPushedFcn = createCallbackFcn(app, @b_6_6ButtonPushed, true);
            app.b_6_6.Text = '';
            app.b_6_7 = uibutton(app.Tab, 'push');
            app.b_6_7.ButtonPushedFcn = createCallbackFcn(app, @b_6_7ButtonPushed, true);
            app.b_6_7.Text = '';
            app.b_6_8 = uibutton(app.Tab, 'push');
            app.b_6_8.ButtonPushedFcn = createCallbackFcn(app, @b_6_8ButtonPushed, true);
            app.b_6_8.Text = '';
            app.b_6_9 = uibutton(app.Tab, 'push');
            app.b_6_9.ButtonPushedFcn = createCallbackFcn(app, @b_6_9ButtonPushed, true);
            app.b_6_9.Text = '';
            app.b_6_10 = uibutton(app.Tab, 'push');
            app.b_6_10.ButtonPushedFcn = createCallbackFcn(app, @b_6_10ButtonPushed, true);
            app.b_6_10.Text = '';
            app.b_6_11 = uibutton(app.Tab, 'push');
            app.b_6_11.ButtonPushedFcn = createCallbackFcn(app, @b_6_11ButtonPushed, true);
            app.b_6_11.Text = '';
            app.b_6_12 = uibutton(app.Tab, 'push');
            app.b_6_12.ButtonPushedFcn = createCallbackFcn(app, @b_6_12ButtonPushed, true);
            app.b_6_12.Text = '';
            app.b_6_13 = uibutton(app.Tab, 'push');
            app.b_6_13.ButtonPushedFcn = createCallbackFcn(app, @b_6_13ButtonPushed, true);
            app.b_6_13.Text = '';
            app.b_6_14 = uibutton(app.Tab, 'push');
            app.b_6_14.ButtonPushedFcn = createCallbackFcn(app, @b_6_14ButtonPushed, true);
            app.b_6_14.Text = '';
            app.b_6_15 = uibutton(app.Tab, 'push');
            app.b_6_15.ButtonPushedFcn = createCallbackFcn(app, @b_6_15ButtonPushed, true);
            app.b_6_15.Text = '';
            app.b_7_1 = uibutton(app.Tab, 'push');
            app.b_7_1.ButtonPushedFcn = createCallbackFcn(app, @b_7_1ButtonPushed, true);
            app.b_7_1.Text = '';
            app.b_7_2 = uibutton(app.Tab, 'push');
            app.b_7_2.ButtonPushedFcn = createCallbackFcn(app, @b_7_2ButtonPushed, true);
            app.b_7_2.Text = '';
            app.b_7_3 = uibutton(app.Tab, 'push');
            app.b_7_3.ButtonPushedFcn = createCallbackFcn(app, @b_7_3ButtonPushed, true);
            app.b_7_3.Text = '';
            app.b_7_4 = uibutton(app.Tab, 'push');
            app.b_7_4.ButtonPushedFcn = createCallbackFcn(app, @b_7_4ButtonPushed, true);
            app.b_7_4.Text = '';
            app.b_7_5 = uibutton(app.Tab, 'push');
            app.b_7_5.ButtonPushedFcn = createCallbackFcn(app, @b_7_5ButtonPushed, true);
            app.b_7_5.Text = '';
            app.b_7_6 = uibutton(app.Tab, 'push');
            app.b_7_6.ButtonPushedFcn = createCallbackFcn(app, @b_7_6ButtonPushed, true);
            app.b_7_6.Text = '';
            app.b_7_7 = uibutton(app.Tab, 'push');
            app.b_7_7.ButtonPushedFcn = createCallbackFcn(app, @b_7_7ButtonPushed, true);
            app.b_7_7.Text = '';
            app.b_7_8 = uibutton(app.Tab, 'push');
            app.b_7_8.ButtonPushedFcn = createCallbackFcn(app, @b_7_8ButtonPushed, true);
            app.b_7_8.Text = '';
            app.b_7_9 = uibutton(app.Tab, 'push');
            app.b_7_9.ButtonPushedFcn = createCallbackFcn(app, @b_7_9ButtonPushed, true);
            app.b_7_9.Text = '';
            app.b_7_10 = uibutton(app.Tab, 'push');
            app.b_7_10.ButtonPushedFcn = createCallbackFcn(app, @b_7_10ButtonPushed, true);
            app.b_7_10.Text = '';
            app.b_7_11 = uibutton(app.Tab, 'push');
            app.b_7_11.ButtonPushedFcn = createCallbackFcn(app, @b_7_11ButtonPushed, true);
            app.b_7_11.Text = '';
            app.b_7_12 = uibutton(app.Tab, 'push');
            app.b_7_12.ButtonPushedFcn = createCallbackFcn(app, @b_7_12ButtonPushed, true);
            app.b_7_12.Text = '';
            app.b_7_13 = uibutton(app.Tab, 'push');
            app.b_7_13.ButtonPushedFcn = createCallbackFcn(app, @b_7_13ButtonPushed, true);
            app.b_7_13.Text = '';
            app.b_7_14 = uibutton(app.Tab, 'push');
            app.b_7_14.ButtonPushedFcn = createCallbackFcn(app, @b_7_14ButtonPushed, true);
            app.b_7_14.Text = '';
            app.b_7_15 = uibutton(app.Tab, 'push');
            app.b_7_15.ButtonPushedFcn = createCallbackFcn(app, @b_7_15ButtonPushed, true);
            app.b_7_15.Text = '';
            app.b_8_1 = uibutton(app.Tab, 'push');
            app.b_8_1.ButtonPushedFcn = createCallbackFcn(app, @b_8_1ButtonPushed, true);
            app.b_8_1.Text = '';
            app.b_8_2 = uibutton(app.Tab, 'push');
            app.b_8_2.ButtonPushedFcn = createCallbackFcn(app, @b_8_2ButtonPushed, true);
            app.b_8_2.Text = '';
            app.b_8_3 = uibutton(app.Tab, 'push');
            app.b_8_3.ButtonPushedFcn = createCallbackFcn(app, @b_8_3ButtonPushed, true);
            app.b_8_3.Text = '';
            app.b_8_4 = uibutton(app.Tab, 'push');
            app.b_8_4.ButtonPushedFcn = createCallbackFcn(app, @b_8_4ButtonPushed, true);
            app.b_8_4.Text = '';
            app.b_8_5 = uibutton(app.Tab, 'push');
            app.b_8_5.ButtonPushedFcn = createCallbackFcn(app, @b_8_5ButtonPushed, true);
            app.b_8_5.Text = '';
            app.b_8_6 = uibutton(app.Tab, 'push');
            app.b_8_6.ButtonPushedFcn = createCallbackFcn(app, @b_8_6ButtonPushed, true);
            app.b_8_6.Text = '';
            app.b_8_7 = uibutton(app.Tab, 'push');
            app.b_8_7.ButtonPushedFcn = createCallbackFcn(app, @b_8_7ButtonPushed, true);
            app.b_8_7.Text = '';
            app.b_8_8 = uibutton(app.Tab, 'push');
            app.b_8_8.ButtonPushedFcn = createCallbackFcn(app, @b_8_8ButtonPushed, true);
            app.b_8_8.Text = '';
            app.b_8_9 = uibutton(app.Tab, 'push');
            app.b_8_9.ButtonPushedFcn = createCallbackFcn(app, @b_8_9ButtonPushed, true);
            app.b_8_9.Text = '';
            app.b_8_10 = uibutton(app.Tab, 'push');
            app.b_8_10.ButtonPushedFcn = createCallbackFcn(app, @b_8_10ButtonPushed, true);
            app.b_8_10.Text = '';
            app.b_8_11 = uibutton(app.Tab, 'push');
            app.b_8_11.ButtonPushedFcn = createCallbackFcn(app, @b_8_11ButtonPushed, true);
            app.b_8_11.Text = '';
            app.b_8_12 = uibutton(app.Tab, 'push');
            app.b_8_12.ButtonPushedFcn = createCallbackFcn(app, @b_8_12ButtonPushed, true);
            app.b_8_12.Text = '';
            app.b_8_13 = uibutton(app.Tab, 'push');
            app.b_8_13.ButtonPushedFcn = createCallbackFcn(app, @b_8_13ButtonPushed, true);
            app.b_8_13.Text = '';
            app.b_8_14 = uibutton(app.Tab, 'push');
            app.b_8_14.ButtonPushedFcn = createCallbackFcn(app, @b_8_14ButtonPushed, true);
            app.b_8_14.Text = '';
            app.b_8_15 = uibutton(app.Tab, 'push');
            app.b_8_15.ButtonPushedFcn = createCallbackFcn(app, @b_8_15ButtonPushed, true);
            app.b_8_15.Text = '';
            app.b_9_1 = uibutton(app.Tab, 'push');
            app.b_9_1.ButtonPushedFcn = createCallbackFcn(app, @b_9_1ButtonPushed, true);
            app.b_9_1.Text = '';
            app.b_9_2 = uibutton(app.Tab, 'push');
            app.b_9_2.ButtonPushedFcn = createCallbackFcn(app, @b_9_2ButtonPushed, true);
            app.b_9_2.Text = '';
            app.b_9_3 = uibutton(app.Tab, 'push');
            app.b_9_3.ButtonPushedFcn = createCallbackFcn(app, @b_9_3ButtonPushed, true);
            app.b_9_3.Text = '';
            app.b_9_4 = uibutton(app.Tab, 'push');
            app.b_9_4.ButtonPushedFcn = createCallbackFcn(app, @b_9_4ButtonPushed, true);
            app.b_9_4.Text = '';
            app.b_9_5 = uibutton(app.Tab, 'push');
            app.b_9_5.ButtonPushedFcn = createCallbackFcn(app, @b_9_5ButtonPushed, true);
            app.b_9_5.Text = '';
            app.b_9_6 = uibutton(app.Tab, 'push');
            app.b_9_6.ButtonPushedFcn = createCallbackFcn(app, @b_9_6ButtonPushed, true);
            app.b_9_6.Text = '';
            app.b_9_7 = uibutton(app.Tab, 'push');
            app.b_9_7.ButtonPushedFcn = createCallbackFcn(app, @b_9_7ButtonPushed, true);
            app.b_9_7.Text = '';
            app.b_9_8 = uibutton(app.Tab, 'push');
            app.b_9_8.ButtonPushedFcn = createCallbackFcn(app, @b_9_8ButtonPushed, true);
            app.b_9_8.Text = '';
            app.b_9_9 = uibutton(app.Tab, 'push');
            app.b_9_9.ButtonPushedFcn = createCallbackFcn(app, @b_9_9ButtonPushed, true);
            app.b_9_9.Text = '';
            app.b_9_10 = uibutton(app.Tab, 'push');
            app.b_9_10.ButtonPushedFcn = createCallbackFcn(app, @b_9_10ButtonPushed, true);
            app.b_9_10.Text = '';
            app.b_9_11 = uibutton(app.Tab, 'push');
            app.b_9_11.ButtonPushedFcn = createCallbackFcn(app, @b_9_11ButtonPushed, true);
            app.b_9_11.Text = '';
            app.b_9_12 = uibutton(app.Tab, 'push');
            app.b_9_12.ButtonPushedFcn = createCallbackFcn(app, @b_9_12ButtonPushed, true);
            app.b_9_12.Text = '';
            app.b_9_13 = uibutton(app.Tab, 'push');
            app.b_9_13.ButtonPushedFcn = createCallbackFcn(app, @b_9_13ButtonPushed, true);
            app.b_9_13.Text = '';
            app.b_9_14 = uibutton(app.Tab, 'push');
            app.b_9_14.ButtonPushedFcn = createCallbackFcn(app, @b_9_14ButtonPushed, true);
            app.b_9_14.Text = '';
            app.b_9_15 = uibutton(app.Tab, 'push');
            app.b_9_15.ButtonPushedFcn = createCallbackFcn(app, @b_9_15ButtonPushed, true);
            app.b_9_15.Text = '';
            app.b_10_1 = uibutton(app.Tab, 'push');
            app.b_10_1.ButtonPushedFcn = createCallbackFcn(app, @b_10_1ButtonPushed, true);
            app.b_10_1.Text = '';
            app.b_10_2 = uibutton(app.Tab, 'push');
            app.b_10_2.ButtonPushedFcn = createCallbackFcn(app, @b_10_2ButtonPushed, true);
            app.b_10_2.Text = '';
            app.b_10_3 = uibutton(app.Tab, 'push');
            app.b_10_3.ButtonPushedFcn = createCallbackFcn(app, @b_10_3ButtonPushed, true);
            app.b_10_3.Text = '';
            app.b_10_4 = uibutton(app.Tab, 'push');
            app.b_10_4.ButtonPushedFcn = createCallbackFcn(app, @b_10_4ButtonPushed, true);
            app.b_10_4.Text = '';
            app.b_10_5 = uibutton(app.Tab, 'push');
            app.b_10_5.ButtonPushedFcn = createCallbackFcn(app, @b_10_5ButtonPushed, true);
            app.b_10_5.Text = '';
            app.b_10_6 = uibutton(app.Tab, 'push');
            app.b_10_6.ButtonPushedFcn = createCallbackFcn(app, @b_10_6ButtonPushed, true);
            app.b_10_6.Text = '';
            app.b_10_7 = uibutton(app.Tab, 'push');
            app.b_10_7.ButtonPushedFcn = createCallbackFcn(app, @b_10_7ButtonPushed, true);
            app.b_10_7.Text = '';
            app.b_10_8 = uibutton(app.Tab, 'push');
            app.b_10_8.ButtonPushedFcn = createCallbackFcn(app, @b_10_8ButtonPushed, true);
            app.b_10_8.Text = '';
            app.b_10_9 = uibutton(app.Tab, 'push');
            app.b_10_9.ButtonPushedFcn = createCallbackFcn(app, @b_10_9ButtonPushed, true);
            app.b_10_9.Text = '';
            app.b_10_10 = uibutton(app.Tab, 'push');
            app.b_10_10.ButtonPushedFcn = createCallbackFcn(app, @b_10_10ButtonPushed, true);
            app.b_10_10.Text = '';
            app.b_10_11 = uibutton(app.Tab, 'push');
            app.b_10_11.ButtonPushedFcn = createCallbackFcn(app, @b_10_11ButtonPushed, true);
            app.b_10_11.Text = '';
            app.b_10_12 = uibutton(app.Tab, 'push');
            app.b_10_12.ButtonPushedFcn = createCallbackFcn(app, @b_10_12ButtonPushed, true);
            app.b_10_12.Text = '';
            app.b_10_13 = uibutton(app.Tab, 'push');
            app.b_10_13.ButtonPushedFcn = createCallbackFcn(app, @b_10_13ButtonPushed, true);
            app.b_10_13.Text = '';
            app.b_10_14 = uibutton(app.Tab, 'push');
            app.b_10_14.ButtonPushedFcn = createCallbackFcn(app, @b_10_14ButtonPushed, true);
            app.b_10_14.Text = '';
            app.b_10_15 = uibutton(app.Tab, 'push');
            app.b_10_15.ButtonPushedFcn = createCallbackFcn(app, @b_10_15ButtonPushed, true);
            app.b_10_15.Text = '';
            app.b_11_1 = uibutton(app.Tab, 'push');
            app.b_11_1.ButtonPushedFcn = createCallbackFcn(app, @b_11_1ButtonPushed, true);
            app.b_11_1.Text = '';
            app.b_11_2 = uibutton(app.Tab, 'push');
            app.b_11_2.ButtonPushedFcn = createCallbackFcn(app, @b_11_2ButtonPushed, true);
            app.b_11_2.Text = '';
            app.b_11_3 = uibutton(app.Tab, 'push');
            app.b_11_3.ButtonPushedFcn = createCallbackFcn(app, @b_11_3ButtonPushed, true);
            app.b_11_3.Text = '';
            app.b_11_4 = uibutton(app.Tab, 'push');
            app.b_11_4.ButtonPushedFcn = createCallbackFcn(app, @b_11_4ButtonPushed, true);
            app.b_11_4.Text = '';
            app.b_11_5 = uibutton(app.Tab, 'push');
            app.b_11_5.ButtonPushedFcn = createCallbackFcn(app, @b_11_5ButtonPushed, true);
            app.b_11_5.Text = '';
            app.b_11_6 = uibutton(app.Tab, 'push');
            app.b_11_6.ButtonPushedFcn = createCallbackFcn(app, @b_11_6ButtonPushed, true);
            app.b_11_6.Text = '';
            app.b_11_7 = uibutton(app.Tab, 'push');
            app.b_11_7.ButtonPushedFcn = createCallbackFcn(app, @b_11_7ButtonPushed, true);
            app.b_11_7.Text = '';
            app.b_11_8 = uibutton(app.Tab, 'push');
            app.b_11_8.ButtonPushedFcn = createCallbackFcn(app, @b_11_8ButtonPushed, true);
            app.b_11_8.Text = '';
            app.b_11_9 = uibutton(app.Tab, 'push');
            app.b_11_9.ButtonPushedFcn = createCallbackFcn(app, @b_11_9ButtonPushed, true);
            app.b_11_9.Text = '';
            app.b_11_10 = uibutton(app.Tab, 'push');
            app.b_11_10.ButtonPushedFcn = createCallbackFcn(app, @b_11_10ButtonPushed, true);
            app.b_11_10.Text = '';
            app.b_11_11 = uibutton(app.Tab, 'push');
            app.b_11_11.ButtonPushedFcn = createCallbackFcn(app, @b_11_11ButtonPushed, true);
            app.b_11_11.Text = '';
            app.b_11_12 = uibutton(app.Tab, 'push');
            app.b_11_12.ButtonPushedFcn = createCallbackFcn(app, @b_11_12ButtonPushed, true);
            app.b_11_12.Text = '';
            app.b_11_13 = uibutton(app.Tab, 'push');
            app.b_11_13.ButtonPushedFcn = createCallbackFcn(app, @b_11_13ButtonPushed, true);
            app.b_11_13.Text = '';
            app.b_11_14 = uibutton(app.Tab, 'push');
            app.b_11_14.ButtonPushedFcn = createCallbackFcn(app, @b_11_14ButtonPushed, true);
            app.b_11_14.Text = '';
            app.b_11_15 = uibutton(app.Tab, 'push');
            app.b_11_15.ButtonPushedFcn = createCallbackFcn(app, @b_11_15ButtonPushed, true);
            app.b_11_15.Text = '';
            app.b_12_1 = uibutton(app.Tab, 'push');
            app.b_12_1.ButtonPushedFcn = createCallbackFcn(app, @b_12_1ButtonPushed, true);
            app.b_12_1.Text = '';
            app.b_12_2 = uibutton(app.Tab, 'push');
            app.b_12_2.ButtonPushedFcn = createCallbackFcn(app, @b_12_2ButtonPushed, true);
            app.b_12_2.Text = '';
            app.b_12_3 = uibutton(app.Tab, 'push');
            app.b_12_3.ButtonPushedFcn = createCallbackFcn(app, @b_12_3ButtonPushed, true);
            app.b_12_3.Text = '';
            app.b_12_4 = uibutton(app.Tab, 'push');
            app.b_12_4.ButtonPushedFcn = createCallbackFcn(app, @b_12_4ButtonPushed, true);
            app.b_12_4.Text = '';
            app.b_12_5 = uibutton(app.Tab, 'push');
            app.b_12_5.ButtonPushedFcn = createCallbackFcn(app, @b_12_5ButtonPushed, true);
            app.b_12_5.Text = '';
            app.b_12_6 = uibutton(app.Tab, 'push');
            app.b_12_6.ButtonPushedFcn = createCallbackFcn(app, @b_12_6ButtonPushed, true);
            app.b_12_6.Text = '';
            app.b_12_7 = uibutton(app.Tab, 'push');
            app.b_12_7.ButtonPushedFcn = createCallbackFcn(app, @b_12_7ButtonPushed, true);
            app.b_12_7.Text = '';
            app.b_12_8 = uibutton(app.Tab, 'push');
            app.b_12_8.ButtonPushedFcn = createCallbackFcn(app, @b_12_8ButtonPushed, true);
            app.b_12_8.Text = '';
            app.b_12_9 = uibutton(app.Tab, 'push');
            app.b_12_9.ButtonPushedFcn = createCallbackFcn(app, @b_12_9ButtonPushed, true);
            app.b_12_9.Text = '';
            app.b_12_10 = uibutton(app.Tab, 'push');
            app.b_12_10.ButtonPushedFcn = createCallbackFcn(app, @b_12_10ButtonPushed, true);
            app.b_12_10.Text = '';
            app.b_12_11 = uibutton(app.Tab, 'push');
            app.b_12_11.ButtonPushedFcn = createCallbackFcn(app, @b_12_11ButtonPushed, true);
            app.b_12_11.Text = '';
            app.b_12_12 = uibutton(app.Tab, 'push');
            app.b_12_12.ButtonPushedFcn = createCallbackFcn(app, @b_12_12ButtonPushed, true);
            app.b_12_12.Text = '';
            app.b_12_13 = uibutton(app.Tab, 'push');
            app.b_12_13.ButtonPushedFcn = createCallbackFcn(app, @b_12_13ButtonPushed, true);
            app.b_12_13.Text = '';
            app.b_12_14 = uibutton(app.Tab, 'push');
            app.b_12_14.ButtonPushedFcn = createCallbackFcn(app, @b_12_14ButtonPushed, true);
            app.b_12_14.Text = '';
            app.b_12_15 = uibutton(app.Tab, 'push');
            app.b_12_15.ButtonPushedFcn = createCallbackFcn(app, @b_12_15ButtonPushed, true);
            app.b_12_15.Text = '';
            app.b_13_1 = uibutton(app.Tab, 'push');
            app.b_13_1.ButtonPushedFcn = createCallbackFcn(app, @b_13_1ButtonPushed, true);
            app.b_13_1.Text = '';
            app.b_13_2 = uibutton(app.Tab, 'push');
            app.b_13_2.ButtonPushedFcn = createCallbackFcn(app, @b_13_2ButtonPushed, true);
            app.b_13_2.Text = '';
            app.b_13_3 = uibutton(app.Tab, 'push');
            app.b_13_3.ButtonPushedFcn = createCallbackFcn(app, @b_13_3ButtonPushed, true);
            app.b_13_3.Text = '';
            app.b_13_4 = uibutton(app.Tab, 'push');
            app.b_13_4.ButtonPushedFcn = createCallbackFcn(app, @b_13_4ButtonPushed, true);
            app.b_13_4.Text = '';
            app.b_13_5 = uibutton(app.Tab, 'push');
            app.b_13_5.ButtonPushedFcn = createCallbackFcn(app, @b_13_5ButtonPushed, true);
            app.b_13_5.Text = '';
            app.b_13_6 = uibutton(app.Tab, 'push');
            app.b_13_6.ButtonPushedFcn = createCallbackFcn(app, @b_13_6ButtonPushed, true);
            app.b_13_6.Text = '';
            app.b_13_7 = uibutton(app.Tab, 'push');
            app.b_13_7.ButtonPushedFcn = createCallbackFcn(app, @b_13_7ButtonPushed, true);
            app.b_13_7.Text = '';
            app.b_13_8 = uibutton(app.Tab, 'push');
            app.b_13_8.ButtonPushedFcn = createCallbackFcn(app, @b_13_8ButtonPushed, true);
            app.b_13_8.Text = '';
            app.b_13_9 = uibutton(app.Tab, 'push');
            app.b_13_9.ButtonPushedFcn = createCallbackFcn(app, @b_13_9ButtonPushed, true);
            app.b_13_9.Text = '';
            app.b_13_10 = uibutton(app.Tab, 'push');
            app.b_13_10.ButtonPushedFcn = createCallbackFcn(app, @b_13_10ButtonPushed, true);
            app.b_13_10.Text = '';
            app.b_13_11 = uibutton(app.Tab, 'push');
            app.b_13_11.ButtonPushedFcn = createCallbackFcn(app, @b_13_11ButtonPushed, true);
            app.b_13_11.Text = '';
            app.b_13_12 = uibutton(app.Tab, 'push');
            app.b_13_12.ButtonPushedFcn = createCallbackFcn(app, @b_13_12ButtonPushed, true);
            app.b_13_12.Text = '';
            app.b_13_13 = uibutton(app.Tab, 'push');
            app.b_13_13.ButtonPushedFcn = createCallbackFcn(app, @b_13_13ButtonPushed, true);
            app.b_13_13.Text = '';
            app.b_13_14 = uibutton(app.Tab, 'push');
            app.b_13_14.ButtonPushedFcn = createCallbackFcn(app, @b_13_14ButtonPushed, true);
            app.b_13_14.Text = '';
            app.b_13_15 = uibutton(app.Tab, 'push');
            app.b_13_15.ButtonPushedFcn = createCallbackFcn(app, @b_13_15ButtonPushed, true);
            app.b_13_15.Text = '';
            app.b_14_1 = uibutton(app.Tab, 'push');
            app.b_14_1.ButtonPushedFcn = createCallbackFcn(app, @b_14_1ButtonPushed, true);
            app.b_14_1.Text = '';
            app.b_14_2 = uibutton(app.Tab, 'push');
            app.b_14_2.ButtonPushedFcn = createCallbackFcn(app, @b_14_2ButtonPushed, true);
            app.b_14_2.Text = '';
            app.b_14_3 = uibutton(app.Tab, 'push');
            app.b_14_3.ButtonPushedFcn = createCallbackFcn(app, @b_14_3ButtonPushed, true);
            app.b_14_3.Text = '';
            app.b_14_4 = uibutton(app.Tab, 'push');
            app.b_14_4.ButtonPushedFcn = createCallbackFcn(app, @b_14_4ButtonPushed, true);
            app.b_14_4.Text = '';
            app.b_14_5 = uibutton(app.Tab, 'push');
            app.b_14_5.ButtonPushedFcn = createCallbackFcn(app, @b_14_5ButtonPushed, true);
            app.b_14_5.Text = '';
            app.b_14_6 = uibutton(app.Tab, 'push');
            app.b_14_6.ButtonPushedFcn = createCallbackFcn(app, @b_14_6ButtonPushed, true);
            app.b_14_6.Text = '';
            app.b_14_7 = uibutton(app.Tab, 'push');
            app.b_14_7.ButtonPushedFcn = createCallbackFcn(app, @b_14_7ButtonPushed, true);
            app.b_14_7.Text = '';
            app.b_14_8 = uibutton(app.Tab, 'push');
            app.b_14_8.ButtonPushedFcn = createCallbackFcn(app, @b_14_8ButtonPushed, true);
            app.b_14_8.Text = '';
            app.b_14_9 = uibutton(app.Tab, 'push');
            app.b_14_9.ButtonPushedFcn = createCallbackFcn(app, @b_14_9ButtonPushed, true);
            app.b_14_9.Text = '';
            app.b_14_10 = uibutton(app.Tab, 'push');
            app.b_14_10.ButtonPushedFcn = createCallbackFcn(app, @b_14_10ButtonPushed, true);
            app.b_14_10.Text = '';
            app.b_14_11 = uibutton(app.Tab, 'push');
            app.b_14_11.ButtonPushedFcn = createCallbackFcn(app, @b_14_11ButtonPushed, true);
            app.b_14_11.Text = '';
            app.b_14_12 = uibutton(app.Tab, 'push');
            app.b_14_12.ButtonPushedFcn = createCallbackFcn(app, @b_14_12ButtonPushed, true);
            app.b_14_12.Text = '';
            app.b_14_13 = uibutton(app.Tab, 'push');
            app.b_14_13.ButtonPushedFcn = createCallbackFcn(app, @b_14_13ButtonPushed, true);
            app.b_14_13.Text = '';
            app.b_14_14 = uibutton(app.Tab, 'push');
            app.b_14_14.ButtonPushedFcn = createCallbackFcn(app, @b_14_14ButtonPushed, true);
            app.b_14_14.Text = '';
            app.b_14_15 = uibutton(app.Tab, 'push');
            app.b_14_15.ButtonPushedFcn = createCallbackFcn(app, @b_14_15ButtonPushed, true);
            app.b_14_15.Text = '';
            app.b_15_1 = uibutton(app.Tab, 'push');
            app.b_15_1.ButtonPushedFcn = createCallbackFcn(app, @b_15_1ButtonPushed, true);
            app.b_15_1.Text = '';
            app.b_15_2 = uibutton(app.Tab, 'push');
            app.b_15_2.ButtonPushedFcn = createCallbackFcn(app, @b_15_2ButtonPushed, true);
            app.b_15_2.Text = '';
            app.b_15_3 = uibutton(app.Tab, 'push');
            app.b_15_3.ButtonPushedFcn = createCallbackFcn(app, @b_15_3ButtonPushed, true);
            app.b_15_3.Text = '';
            app.b_15_4 = uibutton(app.Tab, 'push');
            app.b_15_4.ButtonPushedFcn = createCallbackFcn(app, @b_15_4ButtonPushed, true);
            app.b_15_4.Text = '';
            app.b_15_5 = uibutton(app.Tab, 'push');
            app.b_15_5.ButtonPushedFcn = createCallbackFcn(app, @b_15_5ButtonPushed, true);
            app.b_15_5.Text = '';
            app.b_15_6 = uibutton(app.Tab, 'push');
            app.b_15_6.ButtonPushedFcn = createCallbackFcn(app, @b_15_6ButtonPushed, true);
            app.b_15_6.Text = '';
            app.b_15_7 = uibutton(app.Tab, 'push');
            app.b_15_7.ButtonPushedFcn = createCallbackFcn(app, @b_15_7ButtonPushed, true);
            app.b_15_7.Text = '';
            app.b_15_8 = uibutton(app.Tab, 'push');
            app.b_15_8.ButtonPushedFcn = createCallbackFcn(app, @b_15_8ButtonPushed, true);
            app.b_15_8.Text = '';
            app.b_15_9 = uibutton(app.Tab, 'push');
            app.b_15_9.ButtonPushedFcn = createCallbackFcn(app, @b_15_9ButtonPushed, true);
            app.b_15_9.Text = '';
            app.b_15_10 = uibutton(app.Tab, 'push');
            app.b_15_10.ButtonPushedFcn = createCallbackFcn(app, @b_15_10ButtonPushed, true);
            app.b_15_10.Text = '';
            app.b_15_11 = uibutton(app.Tab, 'push');
            app.b_15_11.ButtonPushedFcn = createCallbackFcn(app, @b_15_11ButtonPushed, true);
            app.b_15_11.Text = '';
            app.b_15_12 = uibutton(app.Tab, 'push');
            app.b_15_12.ButtonPushedFcn = createCallbackFcn(app, @b_15_12ButtonPushed, true);
            app.b_15_12.Text = '';
            app.b_15_13 = uibutton(app.Tab, 'push');
            app.b_15_13.ButtonPushedFcn = createCallbackFcn(app, @b_15_13ButtonPushed, true);
            app.b_15_13.Text = '';
            app.b_15_14 = uibutton(app.Tab, 'push');
            app.b_15_14.ButtonPushedFcn = createCallbackFcn(app, @b_15_14ButtonPushed, true);
            app.b_15_14.Text = '';
            app.b_15_15 = uibutton(app.Tab, 'push');
            app.b_15_15.ButtonPushedFcn = createCallbackFcn(app, @b_15_15ButtonPushed, true);
            app.b_15_15.Text = '';
            app.ClearStartButton = uibutton(app.Tab, 'push');
            app.ClearStartButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStartButtonPushed, true);
            app.ClearStartButton.Text = 'Clear Start';
            app.ClearCiljButton = uibutton(app.Tab, 'push');
            app.ClearCiljButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCiljButtonPushed, true);
            app.ClearCiljButton.Text = 'Clear Cilj';
            app.ClearPreprekaButton = uibutton(app.Tab, 'push');
            app.ClearPreprekaButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPreprekaButtonPushed, true);
            app.ClearPreprekaButton.Text = 'Clear Prepreka';
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.epsilonSliderLabel = uilabel(app.Tab);
            app.epsilonSliderLabel.Text = 'epsilon';
            app.epsilonSlider = uislider(app.Tab);
            app.epsilonSlider.Limits = [0 10];
            app.epsilonSlider.Value = 1;
            app.populationSliderLabel = uilabel(app.Tab);
            app.populationSliderLabel.Text = 'population';
            app.populationSlider = uislider(app.Tab);
            app.populationSlider.Limits = [1 100];
            app.populationSlider.ValueChangedFcn = createCallbackFcn(app, @populationSliderValueChanged, true);
            app.populationSlider.Value = 30;
            app.generationSliderLabel = uilabel(app.Tab);
            app.generationSliderLabel.Text = 'generation';
            app.generationSlider = uislider(app.Tab);
            app.generationSlider.Limits = [1 100];
            app.generationSlider.ValueChangedFcn = createCallbackFcn(app, @generationSliderValueChanged, true);
            app.generationSlider.Value = 30;
            app.lambdaSliderLabel = uilabel(app.Tab);
            app.lambdaSliderLabel.Text = 'lambda';
            app.lambdaSlider = uislider(app.Tab);
            app.lambdaSlider.Limits = [0 10];
            app.lambdaSlider.Value = 1;
            app.crossoverSliderLabel = uilabel(app.Tab);
            app.crossoverSliderLabel.Text = 'crossover';
            app.crossoverSlider = uislider(app.Tab);
            app.crossoverSlider.Limits = [0 1];
            app.crossoverSlider.Value = 0.8;
            app.mutationSliderLabel = uilabel(app.Tab);
            app.mutationSliderLabel.Text = 'mutation';
            app.mutationSlider = uislider(app.Tab);
            app.mutationSlider.Limits = [0 1];
            app.mutationSlider.Value = 0.2;
            app.ClearPathButton = uibutton(app.Tab, 'push');
            app.ClearPathButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPathButtonPushed, true);
            app.ClearPathButton.Text = 'Clear Path';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_clanak

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end